classdef MobileRobotControlGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        CSVLocationEditField          matlab.ui.control.EditField
        CSVLocationEditFieldLabel     matlab.ui.control.Label
        STOPButton                    matlab.ui.control.Button
        SetDataloggingLocationButton  matlab.ui.control.Button
        ResetOccupancyGridButton      matlab.ui.control.Button
        ResetOdometryButton           matlab.ui.control.Button
        RobotEnvironmentPlot          matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
        TabGroup                      matlab.ui.container.TabGroup
        AutonomousControlTab          matlab.ui.container.Tab
        CurrentPoseX                  matlab.ui.control.NumericEditField
        XEditField_2Label             matlab.ui.control.Label
        CurrentPoseY                  matlab.ui.control.NumericEditField
        YEditField_2Label             matlab.ui.control.Label
        CurrentPoseTheta              matlab.ui.control.NumericEditField
        thetaEditField_2Label         matlab.ui.control.Label
        CurrentPoseLabel              matlab.ui.control.Label
        TrajectoryStatusLamp          matlab.ui.control.Lamp
        TrajectoryStatusLampLabel     matlab.ui.control.Label
        GoalPoseLabel                 matlab.ui.control.Label
        GoalPoseTheta                 matlab.ui.control.NumericEditField
        thetaEditFieldLabel           matlab.ui.control.Label
        GoalPoseY                     matlab.ui.control.NumericEditField
        YEditFieldLabel               matlab.ui.control.Label
        GoalPoseX                     matlab.ui.control.NumericEditField
        XEditFieldLabel               matlab.ui.control.Label
        ExecuteTrajectoryButton       matlab.ui.control.Button
        PlanTrajectoryButton          matlab.ui.control.Button
        ManualControlTab              matlab.ui.container.Tab
        RightWheelRPMSlider           matlab.ui.control.Slider
        RightWheelRPMSliderLabel      matlab.ui.control.Label
        LeftWheelRPMSlider            matlab.ui.control.Slider
        LeftWheelRPMSliderLabel       matlab.ui.control.Label
        StopManualControlButton       matlab.ui.control.Button
        ManualActiveLamp              matlab.ui.control.Lamp
        ManualActiveLampLabel         matlab.ui.control.Label
        ActiviateManualControlButton  matlab.ui.control.Button
        ParametersTab                 matlab.ui.container.Tab
        ControlPlot                   matlab.ui.control.UIAxes
        StatePlot                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
        function createOccupancyGrid(app)
            try
                
            catch
                
            end
        end

        function planTrajectory(app,x, y, theta)
            try
                
            catch
                
            end
        end


        function plotExampleData(app)
            try
                % Load Example data from .mat files
                mapFile = uigetfile();
                load(mapFile,'map');
                trajFile= uigetfile();
                load(trajFile,'q');
                mpcFile = uigetfile();
                load(mpcFile, 't', 'xHistory', 'uHistory');
            catch
                error("Failed to Load Example data for plot.");
            end

            % Display Example Data on the Robot Env. Plot
            set(app.UIFigure, 'currentaxes', app.RobotEnvironmentPlot);
            show(map,'Parent',app.RobotEnvironmentPlot);
            hold(app.RobotEnvironmentPlot);
            plot(app.RobotEnvironmentPlot,q(1,1:50:end),q(2,1:50:end),'b--','LineWidth',2);
            plot(app.RobotEnvironmentPlot,q(1,1),q(2,1),'r.',"MarkerSize",30);
            plot(app.RobotEnvironmentPlot,q(1,end),q(2,end),'g.',"MarkerSize",30);
            app.RobotEnvironmentPlot.XGrid = "on";
            app.RobotEnvironmentPlot.YGrid = "on";
            app.RobotEnvironmentPlot.GridColor = 'k';
            app.RobotEnvironmentPlot.GridAlpha = 0.85;
            app.RobotEnvironmentPlot.Title.String = "Robot Environment";
            legend(app.RobotEnvironmentPlot,["Planned Trajectory","Start Pose","Goal Pose"]);

            %Set Current Pose and goal pose
            app.CurrentPoseX.Value = q(1,1);
            app.CurrentPoseY.Value = q(2,1);
            app.CurrentPoseTheta.Value = pi()/2;

            app.GoalPoseX.Value = q(1,end);
            app.GoalPoseY.Value = q(2,end);
            app.GoalPoseTheta.Value = pi()/4;

            % Update State and Input plots
            hold(app.ControlPlot);
            stairs(app.ControlPlot,t,uHistory(1,:));
            stairs(app.ControlPlot,t,uHistory(2,:));
            legend(app.ControlPlot,["\omega_{L} [rad/s]","\omega_{R} [rad/s]"],'Location','southeast');

            hold(app.StatePlot);
            plot(app.StatePlot,t,xHistory(1,:));
            plot(app.StatePlot,t,xHistory(2,:));
            plot(app.StatePlot,t,xHistory(3,:));
            legend(app.StatePlot,["X","Y","\theta"],'Location','southeast');
        end
        function manualInputControl(app)
            try

                rightWheelRPM = app.RightWheelRPMSlider; 
                leftWheelRPM = app.LeftWheelRPMSlider;
                % Convert RPM to pulses per second
                % We have 1120 pulses per revolution
                
                rightWheelPPS= 1120*(rightWheelRPM/60);
                leftWheelPPS = 1120*(leftWheelRPM/60);
                
                % Figure out how to transfer data through serial port
                Voltage = [rightWheelEncoder;leftWheelEncoder];
                T = table(Voltage);
                writeable(T, 'inputVoltage.txt')
                type inputVoltage.txt

            catch
                error("Failed to drive Robot");
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlanTrajectoryButton
        function PlanTrajectoryButtonPushed(app, event)
            plotExampleData(app);
            %XGoal = app.GoalPoseX.Value;
            %YGoal = app.GoalPoseY.Value;
            %ThetaGoal = app.GoalPoseTheta.Value;
            %createOccupancyGrid();
            %planTrajectory(XGoal, YGoal, ThetaGoal);
        end

        % Button pushed function: ActiviateManualControlButton
        function ActiviateManualControlButtonPushed(app, event)
            app.ManualActiveLamp.Color = 'g';
            manualInputControl(app);
                 

        end

        % Button pushed function: StopManualControlButton
        function StopManualControlButtonPushed(app, event)
            app.ManualActiveLamp.Color = 'r';
            rightWheelRPM = 0;
            lefttWheelRPM = 0;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {608, 608};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {516, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.RobotEnvironmentPlot = uiaxes(app.GridLayout2);
            title(app.RobotEnvironmentPlot, 'Robot Environment')
            xlabel(app.RobotEnvironmentPlot, 'X [m]')
            ylabel(app.RobotEnvironmentPlot, 'Y [m]')
            zlabel(app.RobotEnvironmentPlot, 'Z')
            app.ResetOdometryButton = uibutton(app.GridLayout2, 'push');
            app.ResetOdometryButton.Text = 'Reset Odometry';
            app.ResetOccupancyGridButton = uibutton(app.GridLayout2, 'push');
            app.ResetOccupancyGridButton.Text = 'Reset Occupancy Grid';
            app.SetDataloggingLocationButton = uibutton(app.GridLayout2, 'push');
            app.SetDataloggingLocationButton.Text = 'Set Datalogging Location';
            app.STOPButton = uibutton(app.GridLayout2, 'push');
            app.STOPButton.Text = 'STOP';
            app.CSVLocationEditFieldLabel = uilabel(app.GridLayout2);
            app.CSVLocationEditFieldLabel.Text = 'CSV Location';
            app.CSVLocationEditField = uieditfield(app.GridLayout2, 'text');
            app.CSVLocationEditField.Enable = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.StatePlot = uiaxes(app.RightPanel);
            title(app.StatePlot, 'State Variables')
            xlabel(app.StatePlot, 'Time [s]')
            zlabel(app.StatePlot, 'Z')
            app.ControlPlot = uiaxes(app.RightPanel);
            title(app.ControlPlot, 'Control Inputs')
            xlabel(app.ControlPlot, 'Time [s]')
            zlabel(app.ControlPlot, 'Z')
            app.TabGroup = uitabgroup(app.RightPanel);
            app.AutonomousControlTab = uitab(app.TabGroup);
            app.AutonomousControlTab.Title = 'Autonomous Control';
            app.PlanTrajectoryButton = uibutton(app.AutonomousControlTab, 'push');
            app.PlanTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @PlanTrajectoryButtonPushed, true);
            app.PlanTrajectoryButton.Text = 'Plan Trajectory';
            app.ExecuteTrajectoryButton = uibutton(app.AutonomousControlTab, 'push');
            app.ExecuteTrajectoryButton.Text = 'Execute Trajectory';
            app.XEditFieldLabel = uilabel(app.AutonomousControlTab);
            app.XEditFieldLabel.Text = 'X';
            app.GoalPoseX = uieditfield(app.AutonomousControlTab, 'numeric');
            app.YEditFieldLabel = uilabel(app.AutonomousControlTab);
            app.YEditFieldLabel.Text = 'Y';
            app.GoalPoseY = uieditfield(app.AutonomousControlTab, 'numeric');
            app.thetaEditFieldLabel = uilabel(app.AutonomousControlTab);
            app.thetaEditFieldLabel.Interpreter = 'latex';
            app.thetaEditFieldLabel.Text = '\theta';
            app.GoalPoseTheta = uieditfield(app.AutonomousControlTab, 'numeric');
            app.GoalPoseLabel = uilabel(app.AutonomousControlTab);
            app.GoalPoseLabel.Text = 'Goal Pose';
            app.TrajectoryStatusLampLabel = uilabel(app.AutonomousControlTab);
            app.TrajectoryStatusLampLabel.Text = 'Trajectory Status';
            app.TrajectoryStatusLamp = uilamp(app.AutonomousControlTab);
            app.CurrentPoseLabel = uilabel(app.AutonomousControlTab);
            app.CurrentPoseLabel.Text = 'Current Pose';
            app.thetaEditField_2Label = uilabel(app.AutonomousControlTab);
            app.thetaEditField_2Label.Interpreter = 'latex';
            app.thetaEditField_2Label.Text = '\theta';
            app.CurrentPoseTheta = uieditfield(app.AutonomousControlTab, 'numeric');
            app.CurrentPoseTheta.Enable = 'off';
            app.YEditField_2Label = uilabel(app.AutonomousControlTab);
            app.YEditField_2Label.Text = 'Y';
            app.CurrentPoseY = uieditfield(app.AutonomousControlTab, 'numeric');
            app.CurrentPoseY.Enable = 'off';
            app.XEditField_2Label = uilabel(app.AutonomousControlTab);
            app.XEditField_2Label.Text = 'X';
            app.CurrentPoseX = uieditfield(app.AutonomousControlTab, 'numeric');
            app.CurrentPoseX.Enable = 'off';
            app.ManualControlTab = uitab(app.TabGroup);
            app.ManualControlTab.Title = 'Manual Control';
            app.ActiviateManualControlButton = uibutton(app.ManualControlTab, 'push');
            app.ActiviateManualControlButton.ButtonPushedFcn = createCallbackFcn(app, @ActiviateManualControlButtonPushed, true);
            app.ActiviateManualControlButton.Text = {'Activiate'; 'Manual Control'};
            app.ManualActiveLampLabel = uilabel(app.ManualControlTab);
            app.ManualActiveLampLabel.Text = 'Manual Active';
            app.ManualActiveLamp = uilamp(app.ManualControlTab);
            app.StopManualControlButton = uibutton(app.ManualControlTab, 'push');
            app.StopManualControlButton.ButtonPushedFcn = createCallbackFcn(app, @StopManualControlButtonPushed, true);
            app.StopManualControlButton.Text = {'Stop'; 'Manual Control'};
            app.LeftWheelRPMSliderLabel = uilabel(app.ManualControlTab);
            app.LeftWheelRPMSliderLabel.Text = {'Left'; 'Wheel'; '(RPM)'};
            app.LeftWheelRPMSlider = uislider(app.ManualControlTab);
            app.LeftWheelRPMSlider.Limits = [-250 250];
            app.RightWheelRPMSliderLabel = uilabel(app.ManualControlTab);
            app.RightWheelRPMSliderLabel.Text = {'Right'; 'Wheel'; '(RPM)'};
            app.RightWheelRPMSlider = uislider(app.ManualControlTab);
            app.RightWheelRPMSlider.Limits = [-250 250];
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MobileRobotControlGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end